classdef Batterymodel_postprocessing_alongdirection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VideoframerateEditField       matlab.ui.control.NumericEditField
        VideoframerateEditFieldLabel  matlab.ui.control.Label
        toEditField                   matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        FromEditField                 matlab.ui.control.NumericEditField
        FromEditFieldLabel            matlab.ui.control.Label
        Video_Button                  matlab.ui.control.Button
        FormatPanel                   matlab.ui.container.Panel
        FontsizelegendEditField       matlab.ui.control.NumericEditField
        FontsizelegendEditFieldLabel  matlab.ui.control.Label
        FontsizeaxeEditField          matlab.ui.control.NumericEditField
        FontsizeaxeEditFieldLabel     matlab.ui.control.Label
        Grid_DropDown                 matlab.ui.control.DropDown
        GridDropDownLabel             matlab.ui.control.Label
        LegendDropDown                matlab.ui.control.DropDown
        LegendDropDownLabel           matlab.ui.control.Label
        YaxisPanel                    matlab.ui.container.Panel
        Plot_DropDown                 matlab.ui.control.DropDown
        PlotDropDownLabel             matlab.ui.control.Label
        YrightmaxEditField            matlab.ui.control.NumericEditField
        YrightmaxEditFieldLabel       matlab.ui.control.Label
        YrightminEditField            matlab.ui.control.NumericEditField
        YrightminEditFieldLabel       matlab.ui.control.Label
        YrightlimitsDropDown          matlab.ui.control.DropDown
        YrightlimitsDropDownLabel     matlab.ui.control.Label
        YleftmaxEditField             matlab.ui.control.NumericEditField
        YleftmaxEditFieldLabel        matlab.ui.control.Label
        YleftminEditField             matlab.ui.control.NumericEditField
        YleftminEditFieldLabel        matlab.ui.control.Label
        YleftlimitsDropDown           matlab.ui.control.DropDown
        YleftlimitsDropDownLabel      matlab.ui.control.Label
        NormalizedconcentrationCheckBox  matlab.ui.control.CheckBox
        Stop_Button                   matlab.ui.control.StateButton
        Play_Button                   matlab.ui.control.Button
        Format_UITable                matlab.ui.control.Table
        TimexsstepxyLabel             matlab.ui.control.Label
        Button_mm                     matlab.ui.control.Button
        Button_m                      matlab.ui.control.Button
        Button_pp                     matlab.ui.control.Button
        Button_p                      matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        plot_UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Field = [];
        Domain = [];
        n_input = [];
        alltime = [];
        n_domain = [];
        n_field = [];
        n_step = [];
        force_interpolation = [];
        current_step = [];
        minmax_extremums_field = [];
        minmax_mean_field = [];
        minmax_std_field = [];
        minmax_y_plot = zeros(2,3,2);
        minmax_pos = zeros(3,2);
        minmax_pos_plot = zeros(1,2);
        visfield = [];
        colorline = [];
        kfieldref = [];
        kinputref = [];
        kdomainref = [];
        conc_norm = [28000 49600 1200]; % Anode, cathode, electrolyte
    end
    
    methods (Access = private)
        
        function [] = Update_axis(app)

            % Update text
            app.TimexsstepxyLabel.Text = sprintf('Time: %1.1f (s), step %i/%i',app.alltime(app.current_step),app.current_step,app.n_step);

            % Refresh axe
            arrayfun(@cla,findall(0,'type','axes'))

            % Reference
            idref = find(cell2mat(app.Format_UITable.Data(:,6)));
            
            hold(app.plot_UIAxes,'on');
            h_title=title(app.plot_UIAxes,sprintf('Time: %1.1f (s)',app.alltime(app.current_step)));
            if strcmp(app.Plot_DropDown.Value,'Std')
                k_legend = 0;
                k_table = 0;
                for kfield = 1:1:app.n_field
                    if app.n_field==2
                        if kfield==1 
                            yyaxis(app.plot_UIAxes,'left');
                            arrayfun(@cla,findall(0,'type','axes'))
                        else
                            yyaxis(app.plot_UIAxes,'right');
                            arrayfun(@cla,findall(0,'type','axes'))
                            app.plot_UIAxes.YAxis(1).Color = 'k';
                            app.plot_UIAxes.YAxis(2).Color = 'k';
                        end
                    end
                    if strcmp(app.Field(kfield).name,'Concentration')
                        if app.NormalizedconcentrationCheckBox.Value
                            c_norm =app.conc_norm;
                        else
                            c_norm =[1 1 1];
                        end
                    else
                        c_norm =[1 1 1];
                    end
                    for kinput=1:1:app.n_input
                        for kdomain = 1:1:app.n_domain
                            k_table = k_table+1;
                            if cell2mat(app.Format_UITable.Data(k_table,5))
                                k_legend = k_legend+1;
                                if app.n_input==1 && ~app.force_interpolation
                                    x = app.Field(kfield).Domain(kdomain).Data(kinput).pos;
                                    y_std = app.Field(kfield).Domain(kdomain).Data(kinput).std(:,app.current_step)/c_norm(kdomain);
                                else
                                    x = app.Field(kfield).Domain(kdomain).Data(kinput).pos_inter;
                                    y_std = app.Field(kfield).Domain(kdomain).Data(kinput).std_inter(:,app.current_step)/c_norm(kdomain);
                                end

                                if ~isempty(idref)
                                    if app.n_input==1 && ~app.force_interpolation
                                        xref = app.Field(app.kfieldref).Domain(app.kdomainref).Data(app.kinputref).pos;
                                        yref_std = app.Field(app.kfieldref).Domain(kdomain).Data(app.kinputref).std(:,app.current_step)/c_norm(kdomain);
                                    else
                                        xref = app.Field(app.kfieldref).Domain(app.kdomainref).Data(app.kinputref).pos_inter;
                                        yref_std = app.Field(app.kfieldref).Domain(app.kdomainref).Data(app.kinputref).std_inter(:,app.current_step)/c_norm(kdomain);
                                    end
                                    y2_std = interp1(x,y_std,xref,'linear');
                                    x = xref;
                                    y_std = y2_std-yref_std;
                                end
                                
                                str_legend(k_legend).name = char(app.Format_UITable.Data(k_table,1));
                                plot(app.plot_UIAxes,x,y_std,'Linewidth',cell2mat(app.Format_UITable.Data(k_table,3)),'LineStyle',char(app.Format_UITable.Data(k_table,4)),'Color',app.colorline(k_table,:));
                            end
                        end
                    end
                end

            else
                k_legend = 0;
                k_table = 0;
                for kfield = 1:1:app.n_field
                    if app.n_field==2
                        if kfield==1
                            yyaxis(app.plot_UIAxes,'left');
                            arrayfun(@cla,findall(0,'type','axes'))
                        else
                            yyaxis(app.plot_UIAxes,'right');
                            arrayfun(@cla,findall(0,'type','axes'))
                            app.plot_UIAxes.YAxis(1).Color = 'k';
                            app.plot_UIAxes.YAxis(2).Color = 'k';
                        end
                    end
                    if strcmp(app.Field(kfield).name,'Concentration')
                        if app.NormalizedconcentrationCheckBox.Value
                            c_norm =app.conc_norm;
                        else
                            c_norm =[1 1 1];
                        end
                    else
                        c_norm =[1 1 1];
                    end
                    for kinput=1:1:app.n_input
                        for kdomain = 1:1:app.n_domain
                            k_table = k_table+1;
                            if cell2mat(app.Format_UITable.Data(k_table,5))
                                k_legend = k_legend+1;
                                if app.n_input==1 && ~app.force_interpolation
                                    x = app.Field(kfield).Domain(kdomain).Data(kinput).pos;
                                    y_mean = app.Field(kfield).Domain(kdomain).Data(kinput).mean(:,app.current_step)/c_norm(kdomain);
                                else
                                    x = app.Field(kfield).Domain(kdomain).Data(kinput).pos_inter;
                                    y_mean = app.Field(kfield).Domain(kdomain).Data(kinput).mean_inter(:,app.current_step)/c_norm(kdomain);
                                end

                                if ~isempty(idref)
                                    if app.n_input==1 && ~app.force_interpolation
                                        xref = app.Field(app.kfieldref).Domain(app.kdomainref).Data(app.kinputref).pos;
                                        yref_mean = app.Field(app.kfieldref).Domain(kdomain).Data(app.kinputref).mean(:,app.current_step)/c_norm(kdomain);
                                    else
                                        xref = app.Field(app.kfieldref).Domain(app.kdomainref).Data(app.kinputref).pos_inter;
                                        yref_mean = app.Field(app.kfieldref).Domain(app.kdomainref).Data(app.kinputref).mean_inter(:,app.current_step)/c_norm(kdomain);
                                    end
                                    y2_mean = interp1(x,y_mean,xref,'linear');
                                    x = xref;
                                    y_mean = y2_mean-yref_mean;
                                end


                                str_legend(k_legend).name = char(app.Format_UITable.Data(k_table,1));
                                plot(app.plot_UIAxes,x,y_mean,'Linewidth',cell2mat(app.Format_UITable.Data(k_table,3)),'LineStyle',char(app.Format_UITable.Data(k_table,4)),'Color',app.colorline(k_table,:));
                            end
                        end
                    end
                end
            end

            if strcmp(app.Plot_DropDown.Value,'Mean, extremums, std')
                k_legend = 0;
                k_table = 0;
                for kfield = 1:1:app.n_field
                    if app.n_field==2
                        if kfield==1
                            yyaxis(app.plot_UIAxes,'left');
                            arrayfun(@cla,findall(0,'type','axes'))
                        else
                            yyaxis(app.plot_UIAxes,'right');
                            arrayfun(@cla,findall(0,'type','axes'))
                            app.plot_UIAxes.YAxis(1).Color = 'k';
                            app.plot_UIAxes.YAxis(2).Color = 'k';
                        end
                    end
                    if strcmp(app.Field(kfield).name,'Concentration')
                        if app.NormalizedconcentrationCheckBox.Value
                            c_norm =app.conc_norm;
                        else
                            c_norm =[1 1 1];
                        end
                    else
                        c_norm =[1 1 1];
                    end
                    for kinput=1:1:app.n_input
                        for kdomain = 1:1:app.n_domain
                            k_table = k_table+1;

                            if cell2mat(app.Format_UITable.Data(k_table,5))
                                k_legend = k_legend+1;
                                if app.n_input==1 && ~app.force_interpolation
                                    x = app.Field(kfield).Domain(kdomain).Data(kinput).pos;
                                    y_min = app.Field(kfield).Domain(kdomain).Data(kinput).min(:,app.current_step)/c_norm(kdomain);
                                    y_mean = app.Field(kfield).Domain(kdomain).Data(kinput).mean(:,app.current_step)/c_norm(kdomain);
                                    y_max = app.Field(kfield).Domain(kdomain).Data(kinput).max(:,app.current_step)/c_norm(kdomain);
                                    y_std = app.Field(kfield).Domain(kdomain).Data(kinput).std(:,app.current_step)/c_norm(kdomain);
                                else
                                    x = app.Field(kfield).Domain(kdomain).Data(kinput).pos_inter;
                                    y_min = app.Field(kfield).Domain(kdomain).Data(kinput).min_inter(:,app.current_step)/c_norm(kdomain);
                                    y_mean = app.Field(kfield).Domain(kdomain).Data(kinput).mean_inter(:,app.current_step)/c_norm(kdomain);
                                    y_max = app.Field(kfield).Domain(kdomain).Data(kinput).max_inter(:,app.current_step)/c_norm(kdomain);
                                    y_std = app.Field(kfield).Domain(kdomain).Data(kinput).std_inter(:,app.current_step)/c_norm(kdomain);
                                end
                                %str_legend(k_legend).name = char(app.Format_UITable.Data(k_table,1));
                                %plot(app.plot_UIAxes,x,y_mean,'Linewidth',cell2mat(app.Format_UITable.Data(k_table,3)),'LineStyle',char(app.Format_UITable.Data(k_table,4)),'Color',app.colorline(k_table,:));

                                plot(app.plot_UIAxes,x,y_min,'Linewidth',cell2mat(app.Format_UITable.Data(k_table,3))/2,'LineStyle','--','Color',app.colorline(k_table,:));
                                plot(app.plot_UIAxes,x,y_max,'Linewidth',cell2mat(app.Format_UITable.Data(k_table,3))/2,'LineStyle','--','Color',app.colorline(k_table,:));

                                std_min = (y_mean - y_std)';
                                std_max = (y_mean + y_std)';
                                x2 = [x', fliplr(x')];
                                inBetween = [std_min, fliplr(std_max)];
                                h_=fill(app.plot_UIAxes,x2, inBetween, app.colorline(k_table,:));
                                set(h_,'LineStyle','none','FaceAlpha',0.25);
                            end
                        end
                    end
                end
            end

            % X scale
            xlabel(app.plot_UIAxes,'Position along cell thickness (\mum)');
            xlim(app.plot_UIAxes,[app.minmax_pos_plot(1,1) app.minmax_pos_plot(1,2)])
             
            % Y scale
            for kfield = 1:1:app.n_field
                if app.n_field==2
                    if kfield==1
                        yyaxis(app.plot_UIAxes,'left');
                    else
                        yyaxis(app.plot_UIAxes,'right');
                    end
                end
                if strcmp(app.Field(kfield).name,'Concentration')
                    if app.NormalizedconcentrationCheckBox.Value
                        ylabel(app.plot_UIAxes,[app.Field(kfield).name ' normalized']);
                    else
                        ylabel(app.plot_UIAxes,[app.Field(kfield).name ' (mol.m^{-3})']);
                    end
                else
                    ylabel(app.plot_UIAxes,[app.Field(kfield).name ' (V)']);
                end
            end
          
            for kfield = 1:1:app.n_field
                if app.visfield(kfield)
                    if app.n_field==2
                        if kfield==1
                            yyaxis(app.plot_UIAxes,'left');
                            ychoice = app.YleftlimitsDropDown.Value;
                            custom_val = [app.YleftminEditField.Value app.YleftmaxEditField.Value];
                        else
                            yyaxis(app.plot_UIAxes,'right');
                            ychoice = app.YrightlimitsDropDown.Value;
                            custom_val = [app.YrightminEditField.Value app.YrightmaxEditField.Value];
                        end
                    else
                        ychoice = app.YleftlimitsDropDown.Value;
                        custom_val = [app.YleftminEditField.Value app.YleftmaxEditField.Value];
                    end
                    if strcmp(ychoice,'Auto')
                        ylim(app.plot_UIAxes,[-inf inf])
                    elseif strcmp(ychoice,'Global mean bounds')
                        if strcmp(app.Plot_DropDown.Value,'Std')
                            ylim(app.plot_UIAxes,[app.minmax_y_plot(kfield,3,1) app.minmax_y_plot(kfield,3,2)])
                        else
                            ylim(app.plot_UIAxes,[app.minmax_y_plot(kfield,2,1) app.minmax_y_plot(kfield,2,2)])
                        end
                    elseif strcmp(ychoice,'Global extremums bounds')
                        if strcmp(app.Plot_DropDown.Value,'Std')
                            ylim(app.plot_UIAxes,[app.minmax_y_plot(kfield,3,1) app.minmax_y_plot(kfield,3,2)])
                        else
                            ylim(app.plot_UIAxes,[app.minmax_y_plot(kfield,1,1) app.minmax_y_plot(kfield,1,2)])
                        end
                    elseif strcmp(ychoice,'Custom')
                        ylim(app.plot_UIAxes,custom_val)
                    end
                end
            end

            % Grid
            if strcmp(app.Grid_DropDown.Value,'Major and minor grid')
                grid(app.plot_UIAxes,'on');
                set(app.plot_UIAxes,'XMinorGrid','on','YMinorGrid','on'); 
            elseif strcmp(app.Grid_DropDown.Value,'Major grid')
                grid(app.plot_UIAxes,'on');
                set(app.plot_UIAxes,'XMinorGrid','off','YMinorGrid','off'); 
             elseif strcmp(app.Grid_DropDown.Value,'No grid')
                grid(app.plot_UIAxes,'off');
                set(app.plot_UIAxes,'XMinorGrid','off','YMinorGrid','off'); 
            end
               
            % Font
            set(app.plot_UIAxes,'FontName','Times new roman','FontSize',app.FontsizeaxeEditField.Value); % Fontname and fontsize
            set(h_title,'FontName','Times new roman','FontSize',app.FontsizeaxeEditField.Value+2);

            % Legend
            if ~strcmp(app.LegendDropDown.Value,'none')
                h_legend = legend(app.plot_UIAxes,str_legend.name,'Location',char(app.LegendDropDown.Value));
                h_legend.FontSize = app.FontsizelegendEditField.Value;
            else
                legend('off');
            end
            hold(app.plot_UIAxes,'off');            
        end
        
        function [] = Set_axis_scale(app)
            vis = cell2mat(app.Format_UITable.Data(:,5));

            % X-axis
            tmp_min = zeros(app.n_input,app.n_domain)+1e9;
            tmp_max = zeros(app.n_input,app.n_domain)-1e9;
            ktable=0;
            for kfield = 1:1:app.n_field
                for kinput=1:1:app.n_input
                    for kdomain = 1:1:app.n_domain
                        ktable = ktable + 1;
                        if vis(ktable)
                            tmp_min(kinput,kdomain) = app.minmax_pos(kinput,kdomain,1);
                            tmp_max(kinput,kdomain) = app.minmax_pos(kinput,kdomain,2);
                        end
                    end
                end
            end
            app.minmax_pos_plot(1,1)  = min(min(tmp_min));
            app.minmax_pos_plot(1,2)  = max(max(tmp_max));

            % Y-axis left
            ktable=0;
            app.visfield = [0 0];
            for kfield = 1:1:app.n_field
                if strcmp(app.Field(kfield).name,'Concentration')
                    if app.NormalizedconcentrationCheckBox.Value
                        c_norm =app.conc_norm;
                    else
                        c_norm =[1 1 1];
                    end
                else
                    c_norm =[1 1 1];
                end
                tmp_extr_min = zeros(app.n_input,app.n_domain)+1e9;
                tmp_extr_max = zeros(app.n_input,app.n_domain)-1e9;
                tmp_mean_min = zeros(app.n_input,app.n_domain)+1e9;
                tmp_mean_max = zeros(app.n_input,app.n_domain)-1e9;
                tmp_std_min = zeros(app.n_input,app.n_domain)+1e9;
                tmp_std_max = zeros(app.n_input,app.n_domain)-1e9;                
                for kinput=1:1:app.n_input
                    for kdomain = 1:1:app.n_domain
                        ktable = ktable + 1;
                        if vis(ktable)
                            app.visfield(kfield) = 1;
                            tmp_extr_min(kinput,kdomain) = app.minmax_extremums_field(kinput,kdomain,kfield,1)/c_norm(kdomain);
                            tmp_extr_max(kinput,kdomain) = app.minmax_extremums_field(kinput,kdomain,kfield,2)/c_norm(kdomain);
                            tmp_mean_min(kinput,kdomain) = app.minmax_mean_field(kinput,kdomain,kfield,1)/c_norm(kdomain);
                            tmp_mean_max(kinput,kdomain) = app.minmax_mean_field(kinput,kdomain,kfield,2)/c_norm(kdomain);
                            tmp_std_min(kinput,kdomain) = app.minmax_std_field(kinput,kdomain,kfield,1)/c_norm(kdomain);
                            tmp_std_max(kinput,kdomain) = app.minmax_std_field(kinput,kdomain,kfield,2)/c_norm(kdomain);                            
                        end
                    end
                end
                app.minmax_y_plot(kfield,1,1)  = min(min(tmp_extr_min));
                app.minmax_y_plot(kfield,1,2)  = max(max(tmp_extr_max));
                app.minmax_y_plot(kfield,2,1)  = min(min(tmp_mean_min));
                app.minmax_y_plot(kfield,2,2)  = max(max(tmp_mean_max));
                app.minmax_y_plot(kfield,3,1)  = min(min(tmp_std_min));
                app.minmax_y_plot(kfield,3,2)  = max(max(tmp_std_max));
            end  
            app.minmax_y_plot
            app.minmax_y_plot(1,1,1)
            app.minmax_y_plot(1,1,2)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data_field1_domain1, Data_field2_domain1, Data_field1_domain2, Data_field2_domain2, Data_field1_domain3, Data_field2_domain3, Field1, Field2, Domain1, Domain2, Domain3, force_interpolation)
            app.Field(1).Domain(1).Data = Data_field1_domain1;
            app.Field(2).Domain(1).Data = Data_field2_domain1;
            app.Field(1).Domain(2).Data = Data_field1_domain2;
            app.Field(2).Domain(2).Data = Data_field2_domain2;
            app.Field(1).Domain(3).Data = Data_field1_domain3;
            app.Field(2).Domain(3).Data = Data_field2_domain3;           
            app.Field(1).name = Field1;
            app.Field(2).name = Field2;
            app.Domain(1).name = Domain1;
            app.Domain(2).name = Domain2;            
            app.Domain(3).name = Domain3; 
            app.force_interpolation = force_interpolation;
            app.n_input = length(Data_field1_domain1);

            app.n_domain = 3;
            if strcmp(app.Domain(3).name,'None')
                app.n_domain = 2;
                if strcmp(app.Domain(2).name,'None')
                    app.n_domain = 1;
                end
            end
            app.n_field = 2;
            if strcmp(app.Field(2).name,'None')
                app.n_field = 1;
                app.YrightlimitsDropDown.Enable = 'off'; app.YrightlimitsDropDown.Visible = 'off';
                app.YrightlimitsDropDownLabel.Enable = 'off'; app.YrightlimitsDropDownLabel.Visible = 'off';

                app.YrightminEditField.Visible = 'off'; app.YrightminEditFieldLabel.Visible = 'off';
                app.YrightmaxEditField.Visible = 'off'; app.YrightmaxEditFieldLabel.Visible = 'off';
            end            

            app.colorline = zeros(app.n_input*app.n_domain*app.n_field,3);
            app.minmax_pos = zeros(app.n_input,app.n_domain,2);
            app.minmax_extremums_field = zeros(app.n_input,app.n_domain,app.n_field,2);
            app.minmax_mean_field = zeros(app.n_input,app.n_domain,app.n_field,2);
            app.minmax_std_field = zeros(app.n_input,app.n_domain,app.n_field,2);
            app.n_step = zeros(app.n_input,1);
            app.alltime = [];
            for kinput=1:1:app.n_input
                if app.n_input==1 && ~app.force_interpolation
                    app.alltime = [app.alltime; app.Field(1).Domain(1).Data(kinput).t];
                    app.n_step(kinput) = length(app.Field(1).Domain(1).Data(kinput).t);
                else
                    app.alltime = [app.alltime; app.Field(1).Domain(1).Data(kinput).t_interp];
                    app.n_step(kinput) = length(app.Field(1).Domain(1).Data(kinput).t_interp);
                end
                for kdomain=1:1:app.n_domain
                    if app.n_input==1 && ~app.force_interpolation
                        app.minmax_pos(kinput,kdomain,1) = min(app.Field(1).Domain(kdomain).Data(kinput).pos);
                        app.minmax_pos(kinput,kdomain,2) = max(app.Field(1).Domain(kdomain).Data(kinput).pos);
                        for kfield=1:1:app.n_field
                            app.minmax_extremums_field(kinput,kdomain,kfield,1) = min(min(app.Field(kfield).Domain(kdomain).Data(kinput).min)); app.minmax_extremums_field(kinput,kdomain,kfield,2) = max(max(app.Field(kfield).Domain(kdomain).Data(kinput).max));
                            app.minmax_mean_field(kinput,kdomain,kfield,1) = min(min(app.Field(kfield).Domain(kdomain).Data(kinput).mean)); app.minmax_mean_field(kinput,kdomain,kfield,2) = max(max(app.Field(kfield).Domain(kdomain).Data(kinput).mean));
                            app.minmax_std_field(kinput,kdomain,kfield,1) = min(min(app.Field(kfield).Domain(kdomain).Data(kinput).std)); app.minmax_std_field(kinput,kdomain,kfield,2) = max(max(app.Field(kfield).Domain(kdomain).Data(kinput).std));
                        end
                    else
                        app.minmax_pos(kinput,kdomain,1) = min(app.Field(1).Domain(kdomain).Data(kinput).pos_inter);
                        app.minmax_pos(kinput,kdomain,2) = max(app.Field(1).Domain(kdomain).Data(kinput).pos_inter);
                        for kfield=1:1:app.n_field
                            app.minmax_extremums_field(kinput,kdomain,kfield,1) = min(min(app.Field(kfield).Domain(kdomain).Data(kinput).min_inter)); app.minmax_extremums_field(kinput,kdomain,kfield,2) = max(max(app.Field(kfield).Domain(kdomain).Data(kinput).max_inter));
                            app.minmax_mean_field(kinput,kdomain,kfield,1) = min(min(app.Field(kfield).Domain(kdomain).Data(kinput).mean_inter)); app.minmax_mean_field(kinput,kdomain,kfield,2) = max(max(app.Field(kfield).Domain(kdomain).Data(kinput).mean_inter));
                            app.minmax_std_field(kinput,kdomain,kfield,1) = min(min(app.Field(kfield).Domain(kdomain).Data(kinput).std_inter)); app.minmax_std_field(kinput,kdomain,kfield,2) = max(max(app.Field(kfield).Domain(kdomain).Data(kinput).std_inter));
                        end
                    end
                end
            end

            % Slider
            app.alltime = unique(app.alltime);
            t = [min(app.alltime) max(app.alltime)];
            app.n_step = max(app.n_step);
            app.Slider.Limits = [t(1) t(2)];

            % From to
            app.toEditField.Value = app.n_step;
            app.toEditField.Limits = [2 app.n_step];
            app.FromEditField.Limits = [1 app.n_step-1];

            % Table format
            col_ = [colororder; rand(100,3)];
            tmp = cell(app.n_input*app.n_domain*app.n_field,6);
            k=0;
            for kfield = 1:1:app.n_field
                for kinput=1:1:app.n_input
                    for kdomain = 1:1:app.n_domain
                        k=k+1;
                        % Legend
                        tmp(k,1)={[app.Field(1).Domain(kdomain).Data(kinput).name ', ' app.Field(kfield).name]};
                        % Color
                        app.colorline(k,:) = col_(k,:);
                        s = uistyle('BackgroundColor',app.colorline(k,:));
                        addStyle(app.Format_UITable,s,'cell',[k,2]);
                        % LineWidth
                        tmp(k,3)={2};
                        % LineStyle
                        tmp(k,4)={'-'};
                        % Visible
                        tmp(k,5)={true};
                        % Reference
                        tmp(k,6)={false};
                        % Overlay
                        tmp(k,7)={false};
                    end
                end
            end         
            app.Format_UITable.Data = tmp;

            % Initialize axe
            app.current_step=1;
            app.Set_axis_scale; % Set x-axis and y-axis limit
            app.Update_axis();
        end

        % Button pushed function: Button_p
        function Button_pPushed(app, event)
            app.current_step = min([app.current_step+1 app.n_step]);
            app.Slider.Value = app.alltime(app.current_step) ;
            app.Update_axis();
        end

        % Button pushed function: Button_pp
        function Button_ppPushed(app, event)
            app.current_step = min([round(app.current_step+app.n_step/10) app.n_step]);
            app.Slider.Value = app.alltime(app.current_step) ;
            app.Update_axis();            
        end

        % Button pushed function: Button_m
        function Button_mPushed(app, event)
            app.current_step = max([app.current_step-1 1]);
            app.Slider.Value = app.alltime(app.current_step) ;
            app.Update_axis();            
        end

        % Button pushed function: Button_mm
        function Button_mmPushed(app, event)
            app.current_step = max([round(app.current_step-app.n_step/10) 1]);
            app.Slider.Value = app.alltime(app.current_step) ;
            app.Update_axis();                 
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            d = abs(app.alltime - value);
            app.current_step = find( d == min(d) );
            app.Update_axis();               
        end

        % Value changed function: YleftlimitsDropDown
        function YleftlimitsDropDownValueChanged(app, event)
            value = app.YleftlimitsDropDown.Value;
            if strcmp(value,'Custom')
                app.YleftminEditField.Enable = 'on'; app.YleftminEditFieldLabel.Enable = 'on';
                app.YleftmaxEditField.Enable = 'on'; app.YleftmaxEditFieldLabel.Enable = 'on';
            else
                app.YleftminEditField.Enable = 'off'; app.YleftminEditFieldLabel.Enable = 'off';
                app.YleftmaxEditField.Enable = 'off'; app.YleftmaxEditFieldLabel.Enable = 'off';
            end
            app.Update_axis();             
        end

        % Value changed function: YleftmaxEditField, YleftminEditField
        function YleftminEditFieldValueChanged(app, event)
            value_min = app.YleftminEditField.Value;
            value_max = app.YleftmaxEditField.Value;
            if value_max<value_min
                app.YleftminEditField.Value=0;
                app.YleftmaxEditField.Value=1;
            end
            app.Update_axis();            
        end

        % Value changed function: Grid_DropDown
        function Grid_DropDownValueChanged(app, event)
            app.Update_axis();            
        end

        % Value changed function: FontsizeaxeEditField, 
        % ...and 1 other component
        function FontsizeaxeEditFieldValueChanged(app, event)
            app.Update_axis();            
        end

        % Button pushed function: Play_Button
        function Play_ButtonPushed(app, event)
            k=0;
            app.current_step = app.FromEditField.Value;
            app.Slider.Value = app.alltime(app.current_step) ;
            app.Update_axis();
            while app.current_step<=app.n_step && app.current_step<=app.toEditField.Value
                pause(0.01)
                if app.Stop_Button.Value
                    app.Stop_Button.Value = false;
                    break
                end
                if k>0
                    app.Button_pPushed;
                end
                k=k+1;
                pause(0.01)
            end            
        end

        % Value changed function: Plot_DropDown
        function Plot_DropDownValueChanged(app, event)
            app.Update_axis();            
        end

        % Cell selection callback: Format_UITable
        function Format_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==2 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.colorline(indices(1),:) = c;
                        s = uistyle('BackgroundColor',c);
                        addStyle(app.Format_UITable,s,'cell',[indices(1),2]);
                    end
                end
            end
            app.Update_axis();
        end

        % Cell edit callback: Format_UITable
        function Format_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==4 % Linestyle
                if ~strcmp(newData,'-') && ~strcmp(newData,'--') && ~strcmp(newData,':') && ~strcmp(newData,'-.')
                    app.Format_UITable.Data(indices(1),4)={'-'};
                end
            elseif indices(2)==5 % Visible
                app.Set_axis_scale;
            elseif indices(2)==6 % Reference
                if newData
                    app.Format_UITable.Data(:,6) = {false};
                    app.Format_UITable.Data(indices(1),6) = {true};
                    %app.Format_UITable.Data(:,5) = {true};
                    app.Format_UITable.Data(indices(1),5) = {false};  

                    ktable = 0;
                    for kfield = 1:1:app.n_field
                        for kinput=1:1:app.n_input
                            for kdomain = 1:1:app.n_domain
                                ktable = ktable + 1;
                                if cell2mat(app.Format_UITable.Data(ktable,6))
                                    app.kfieldref = kfield;
                                    app.kinputref = kinput;
                                    app.kdomainref = kdomain;
                                end
                            end
                        end
                    end
                    app.Plot_DropDown.Items={'Mean only', 'Std'};
                    app.Plot_DropDown.Value = {'Mean only'};
                else
                    app.Plot_DropDown.Items={'Mean, extremums, std', 'Mean only', 'Std'};
                    app.Plot_DropDown.Value = {'Mean, extremums, std'};
                end
            elseif indices(2)==7 % Overlay
                %if sum(app.Format_UITable.Data(:,7))>2
                %    app.Format_UITable.Data(:,7) = {false};
                %end


            end
            app.Update_axis();            
        end

        % Value changed function: LegendDropDown
        function LegendDropDownValueChanged(app, event)
            app.Update_axis();            
        end

        % Value changed function: NormalizedconcentrationCheckBox
        function NormalizedconcentrationCheckBoxValueChanged(app, event)
            app.Set_axis_scale;
            app.Update_axis;
        end

        % Value changed function: YrightlimitsDropDown
        function YrightlimitsDropDownValueChanged(app, event)
            value = app.YrightlimitsDropDown.Value;
            if strcmp(value,'Custom')
                app.YrightminEditField.Enable = 'on'; app.YrightminEditFieldLabel.Enable = 'on';
                app.YrightmaxEditField.Enable = 'on'; app.YrightmaxEditFieldLabel.Enable = 'on';
            else
                app.YrightminEditField.Enable = 'off'; app.YrightminEditFieldLabel.Enable = 'off';
                app.YrightmaxEditField.Enable = 'off'; app.YrightmaxEditFieldLabel.Enable = 'off';
            end
            app.Update_axis();  
        end

        % Button pushed function: Video_Button
        function Video_ButtonPushed(app, event)
            folder = pwd;
            video_filename = 'Video';
            %video_format = 'Uncompressed AVI';
            video_format = 'MPEG-4';
            video_handle = VideoWriter([folder video_filename],video_format);
            set(video_handle,'FrameRate',app.VideoframerateEditField.Value);
            set(video_handle,'Quality',100); 
            open(video_handle)
            frame_number = 0;            
            
            app.current_step = app.FromEditField.Value;
            app.Slider.Value = app.alltime(app.current_step) ;
            app.Update_axis();
            while app.current_step<=app.n_step && app.current_step<=app.toEditField.Value
                pause(0.01)
                if app.Stop_Button.Value
                    app.Stop_Button.Value = false;
                    close(video_handle)
                    break
                end
                if frame_number>0
                    app.Button_pPushed;
                end
                frame_number=frame_number+1;
                stored_frame(frame_number) = getframe(app.UIFigure);
                writeVideo(video_handle,stored_frame(frame_number))
                pause(0.01)
            end    
            close(video_handle)


        end

        % Value changed function: FromEditField, toEditField
        function toEditFieldValueChanged(app, event)
            value_from = app.FromEditField.Value;
            value_to = app.toEditField.Value;
            if value_from>=value_to
                app.FromEditField.Value = 1;
                app.toEditField.Value = app.n_step;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot_UIAxes = uiaxes(app.UIFigure);
            title(app.plot_UIAxes, 'Title')
            xlabel(app.plot_UIAxes, 'X')
            ylabel(app.plot_UIAxes, 'Y')
            zlabel(app.plot_UIAxes, 'Z')
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Button_p = uibutton(app.UIFigure, 'push');
            app.Button_p.ButtonPushedFcn = createCallbackFcn(app, @Button_pPushed, true);
            app.Button_p.Text = '+';
            app.Button_pp = uibutton(app.UIFigure, 'push');
            app.Button_pp.ButtonPushedFcn = createCallbackFcn(app, @Button_ppPushed, true);
            app.Button_pp.Text = '++';
            app.Button_m = uibutton(app.UIFigure, 'push');
            app.Button_m.ButtonPushedFcn = createCallbackFcn(app, @Button_mPushed, true);
            app.Button_m.Text = '-';
            app.Button_mm = uibutton(app.UIFigure, 'push');
            app.Button_mm.ButtonPushedFcn = createCallbackFcn(app, @Button_mmPushed, true);
            app.Button_mm.Text = '--';
            app.TimexsstepxyLabel = uilabel(app.UIFigure);
            app.TimexsstepxyLabel.Text = 'Time: x (s), step x/y';
            app.Format_UITable = uitable(app.UIFigure);
            app.Format_UITable.ColumnName = {'Legend'; 'Color'; 'LineWidth'; 'LineStyle'; 'Visible'; 'Reference'; 'Overlay'};
            app.Format_UITable.RowName = {};
            app.Format_UITable.ColumnEditable = true;
            app.Format_UITable.CellEditCallback = createCallbackFcn(app, @Format_UITableCellEdit, true);
            app.Format_UITable.CellSelectionCallback = createCallbackFcn(app, @Format_UITableCellSelection, true);
            app.Format_UITable.Tooltip = {'LineStyle: - , -- , : , -.'};
            app.Play_Button = uibutton(app.UIFigure, 'push');
            app.Play_Button.ButtonPushedFcn = createCallbackFcn(app, @Play_ButtonPushed, true);
            app.Play_Button.Icon = fullfile(pathToMLAPP, 'Play_icon.png');
            app.Play_Button.Text = '';
            app.Stop_Button = uibutton(app.UIFigure, 'state');
            app.Stop_Button.Text = 'Stop';
            app.YaxisPanel = uipanel(app.UIFigure);
            app.YaxisPanel.Title = 'Y-axis';
            app.NormalizedconcentrationCheckBox = uicheckbox(app.YaxisPanel);
            app.NormalizedconcentrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizedconcentrationCheckBoxValueChanged, true);
            app.NormalizedconcentrationCheckBox.Text = 'Normalized concentration';
            app.YleftlimitsDropDownLabel = uilabel(app.YaxisPanel);
            app.YleftlimitsDropDownLabel.Text = 'Y-left limits';
            app.YleftlimitsDropDown = uidropdown(app.YaxisPanel);
            app.YleftlimitsDropDown.Items = {'Global extremums bounds', 'Global mean bounds', 'Auto', 'Custom'};
            app.YleftlimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @YleftlimitsDropDownValueChanged, true);
            app.YleftlimitsDropDown.Value = 'Global extremums bounds';
            app.YleftminEditFieldLabel = uilabel(app.YaxisPanel);
            app.YleftminEditFieldLabel.Enable = 'off';
            app.YleftminEditFieldLabel.Text = 'Y-left min';
            app.YleftminEditField = uieditfield(app.YaxisPanel, 'numeric');
            app.YleftminEditField.ValueChangedFcn = createCallbackFcn(app, @YleftminEditFieldValueChanged, true);
            app.YleftminEditField.Enable = 'off';
            app.YleftmaxEditFieldLabel = uilabel(app.YaxisPanel);
            app.YleftmaxEditFieldLabel.Enable = 'off';
            app.YleftmaxEditFieldLabel.Text = 'Y-left max';
            app.YleftmaxEditField = uieditfield(app.YaxisPanel, 'numeric');
            app.YleftmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YleftminEditFieldValueChanged, true);
            app.YleftmaxEditField.Enable = 'off';
            app.YleftmaxEditField.Value = 1;
            app.YrightlimitsDropDownLabel = uilabel(app.YaxisPanel);
            app.YrightlimitsDropDownLabel.Text = 'Y-right limits';
            app.YrightlimitsDropDown = uidropdown(app.YaxisPanel);
            app.YrightlimitsDropDown.Items = {'Global extremums bounds', 'Global mean bounds', 'Auto', 'Custom'};
            app.YrightlimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @YrightlimitsDropDownValueChanged, true);
            app.YrightlimitsDropDown.Value = 'Global extremums bounds';
            app.YrightminEditFieldLabel = uilabel(app.YaxisPanel);
            app.YrightminEditFieldLabel.Enable = 'off';
            app.YrightminEditFieldLabel.Text = 'Y-right min';
            app.YrightminEditField = uieditfield(app.YaxisPanel, 'numeric');
            app.YrightminEditField.Enable = 'off';
            app.YrightmaxEditFieldLabel = uilabel(app.YaxisPanel);
            app.YrightmaxEditFieldLabel.Enable = 'off';
            app.YrightmaxEditFieldLabel.Text = 'Y-right max';
            app.YrightmaxEditField = uieditfield(app.YaxisPanel, 'numeric');
            app.YrightmaxEditField.Enable = 'off';
            app.YrightmaxEditField.Value = 1;
            app.PlotDropDownLabel = uilabel(app.YaxisPanel);
            app.PlotDropDownLabel.Text = 'Plot';
            app.Plot_DropDown = uidropdown(app.YaxisPanel);
            app.Plot_DropDown.Items = {'Mean, extremums, std', 'Mean only', 'Std'};
            app.Plot_DropDown.ValueChangedFcn = createCallbackFcn(app, @Plot_DropDownValueChanged, true);
            app.Plot_DropDown.Value = 'Mean, extremums, std';
            app.FormatPanel = uipanel(app.UIFigure);
            app.FormatPanel.Title = 'Format';
            app.LegendDropDownLabel = uilabel(app.FormatPanel);
            app.LegendDropDownLabel.Text = 'Legend';
            app.LegendDropDown = uidropdown(app.FormatPanel);
            app.LegendDropDown.Items = {'north', 'south', 'east', 'west', 'northeast', 'northwest', 'southeast', 'southwest', 'northoutside', 'southoutside', 'eastoutside', 'westoutside', 'northeastoutside', 'northwestoutside', 'southeastoutside', 'southwestoutside', 'best', 'bestoutside', 'none'};
            app.LegendDropDown.ValueChangedFcn = createCallbackFcn(app, @LegendDropDownValueChanged, true);
            app.LegendDropDown.Value = 'north';
            app.GridDropDownLabel = uilabel(app.FormatPanel);
            app.GridDropDownLabel.Text = 'Grid';
            app.Grid_DropDown = uidropdown(app.FormatPanel);
            app.Grid_DropDown.Items = {'Major and minor grid', 'Major grid', 'No grid'};
            app.Grid_DropDown.ValueChangedFcn = createCallbackFcn(app, @Grid_DropDownValueChanged, true);
            app.Grid_DropDown.Value = 'Major and minor grid';
            app.FontsizeaxeEditFieldLabel = uilabel(app.FormatPanel);
            app.FontsizeaxeEditFieldLabel.Text = 'Fontsize axe';
            app.FontsizeaxeEditField = uieditfield(app.FormatPanel, 'numeric');
            app.FontsizeaxeEditField.Limits = [4 Inf];
            app.FontsizeaxeEditField.RoundFractionalValues = 'on';
            app.FontsizeaxeEditField.ValueChangedFcn = createCallbackFcn(app, @FontsizeaxeEditFieldValueChanged, true);
            app.FontsizeaxeEditField.Value = 16;
            app.FontsizelegendEditFieldLabel = uilabel(app.FormatPanel);
            app.FontsizelegendEditFieldLabel.Text = 'Fontsize legend';
            app.FontsizelegendEditField = uieditfield(app.FormatPanel, 'numeric');
            app.FontsizelegendEditField.Limits = [4 Inf];
            app.FontsizelegendEditField.RoundFractionalValues = 'on';
            app.FontsizelegendEditField.ValueChangedFcn = createCallbackFcn(app, @FontsizeaxeEditFieldValueChanged, true);
            app.FontsizelegendEditField.Value = 12;
            app.Video_Button = uibutton(app.UIFigure, 'push');
            app.Video_Button.ButtonPushedFcn = createCallbackFcn(app, @Video_ButtonPushed, true);
            app.Video_Button.Icon = fullfile(pathToMLAPP, 'Play_icon.png');
            app.Video_Button.Text = '';
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField.Limits = [1 Inf];
            app.FromEditField.RoundFractionalValues = 'on';
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.FromEditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Limits = [2 Inf];
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.toEditField.Value = 2;
            app.VideoframerateEditFieldLabel = uilabel(app.UIFigure);
            app.VideoframerateEditFieldLabel.Text = 'Video framerate';
            app.VideoframerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.VideoframerateEditField.Limits = [1 Inf];
            app.VideoframerateEditField.RoundFractionalValues = 'on';
            app.VideoframerateEditField.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Batterymodel_postprocessing_alongdirection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end