classdef Batterymodel_postprocessing_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        HardcodedcnorminalongdirectionmlappLabel  matlab.ui.control.Label
        Import_UITable                  matlab.ui.control.Table
        ImportLabel                     matlab.ui.control.Label
        SelectresultfolderButton        matlab.ui.control.Button
        AlongthicknessTab               matlab.ui.container.Tab
        ForceinterpolationifimportonlyoneresultCheckBox_2  matlab.ui.control.CheckBox
        Domain3DropDown                 matlab.ui.control.DropDown
        Domain3DropDownLabel            matlab.ui.control.Label
        Domain2DropDown                 matlab.ui.control.DropDown
        Domain2DropDownLabel            matlab.ui.control.Label
        AlongDirection_SteptimesEditField  matlab.ui.control.NumericEditField
        SteptimesEditFieldLabel         matlab.ui.control.Label
        AlongDirection_StatutTextArea   matlab.ui.control.TextArea
        StatutTextAreaLabel             matlab.ui.control.Label
        AlongDirection_PlotButton       matlab.ui.control.Button
        Domain1DropDown                 matlab.ui.control.DropDown
        Domain1DropDownLabel            matlab.ui.control.Label
        Field2rightyaxisDropDown        matlab.ui.control.DropDown
        Field2rightyaxisDropDownLabel   matlab.ui.control.Label
        Field1leftyaxisDropDown         matlab.ui.control.DropDown
        Field1leftyaxisDropDownLabel    matlab.ui.control.Label
        PlotAlongDirection_UITable      matlab.ui.control.Table
        PlotsolutionalongthicknessLabel  matlab.ui.control.Label
        CellvoltageTab                  matlab.ui.container.Tab
        ForceinterpolationifimportonlyoneresultCheckBox  matlab.ui.control.CheckBox
        Cellvoltage_SteptimesEditField  matlab.ui.control.NumericEditField
        SteptimesEditFieldLabel_2       matlab.ui.control.Label
        Cellvoltage_StatutTextArea      matlab.ui.control.TextArea
        StatutTextAreaLabel_2           matlab.ui.control.Label
        Cellvoltage_PlotButton          matlab.ui.control.Button
        PlotcellvoltageLabel            matlab.ui.control.Label
        PlotCellvoltage_UITable         matlab.ui.control.Table
        AveragedDOFTab                  matlab.ui.container.Tab
        MasserrorTab                    matlab.ui.container.Tab
    end

    
    properties (Access = private)
        currentfolderpath = []; % Current folder path
        number_simulation = 0;
        simulations = [];
        delimiter = [];
        filenames = []; % Go to startupFcn to see all import files


    end
    
    methods (Access = private)
        
       function filefolder = findfile(app, root, filename)
            % Source: https://www.mathworks.com/matlabcentral/answers/300173-how-can-i-find-the-path-of-a-file
            dircontent = dir(fullfile(root, '*'));
            if any(strcmpi({dircontent(~[dircontent.isdir]).name}, filename))  %case insensitive comparison. Works for Windows only.
                %file is found in directory
                filefolder = root;
            else
                %look in subdirectories
                for subdir = dircontent([dircontent.isdir] & ~ismember({dircontent.name}, {'.', '..'}))'
                    filefolder = app.findfile(fullfile(root, subdir.name), filename);
                    if ~isempty(filefolder)
                        %found in a directory, all done
                        return;
                    end
                end
                %looked in all subdirectories and not found
                filefolder = []; %return empty
            end
        end            
            
        
        function Vals = Select_data_alongdirection(app,currentpath,field,domain)
            filefolder_AMREX2=[];filefolder_AMREX3=[];
            %reverse_xaxis = false;
            if strcmp(field,'Concentration')
                if strcmp(domain,'Anode active material')
                    f_AMREX = app.filenames.AMREX.alongthickness.anode_active_material_concentration; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.anode_active_material_concentration; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.anode_active_material_concentration; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Cathode active material')
                    f_AMREX = app.filenames.AMREX.alongthickness.cathode_active_material_concentration; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.cathode_active_material_concentration; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.cathode_active_material_concentration; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Electrode active materials')
                    f_AMREX = app.filenames.AMREX.alongthickness.cathode_active_material_concentration; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_AMREX2 = app.filenames.AMREX.alongthickness.anode_active_material_concentration; filefolder_AMREX2 = app.findfile(currentpath, f_AMREX2);
                    f_FENICS = app.filenames.FENICS.alongthickness.cathode_active_material_concentration; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_FENICS2 = app.filenames.FENICS.alongthickness.anode_active_material_concentration; filefolder_FENICS2 = app.findfile(currentpath, f_FENICS2);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.cathode_active_material_concentration; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                    f_PYBAMM2 = app.filenames.PYBAMM.alongthickness.anode_active_material_concentration; filefolder_PYBAMM2 = app.findfile(currentpath, f_PYBAMM2);
                elseif strcmp(domain,'Anode electrolyte')
                    f_AMREX = app.filenames.AMREX.alongthickness.anode_electrolyte_concentration; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.electrolyte_concentration; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.electrolyte_concentration; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Separator')
                    f_AMREX = app.filenames.AMREX.alongthickness.separator_concentration; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.electrolyte_concentration; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.electrolyte_concentration; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Cathode electrolyte')
                    f_AMREX = app.filenames.AMREX.alongthickness.cathode_electrolyte_concentration; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.electrolyte_concentration; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.electrolyte_concentration; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Electrolyte')
                    f_AMREX = app.filenames.AMREX.alongthickness.cathode_electrolyte_concentration; filefolder_AMREX3 = app.findfile(currentpath, f_AMREX);
                    f_AMREX2 = app.filenames.AMREX.alongthickness.separator_concentration; filefolder_AMREX2 = app.findfile(currentpath, f_AMREX2);
                    f_AMREX3 = app.filenames.AMREX.alongthickness.anode_electrolyte_concentration; filefolder_AMREX = app.findfile(currentpath, f_AMREX3);
                    f_FENICS = app.filenames.FENICS.alongthickness.electrolyte_concentration; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.electrolyte_concentration; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                end
            elseif strcmp(field,'Potential')
                if strcmp(domain,'Anode active material')
                    f_AMREX = app.filenames.AMREX.alongthickness.anode_active_material_potential; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.anode_active_material_potential; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.anode_active_material_potential; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Cathode active material')
                    f_AMREX = app.filenames.AMREX.alongthickness.cathode_active_material_potential; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.cathode_active_material_potential; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.cathode_active_material_potential; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Electrode active materials')
                    f_AMREX = app.filenames.AMREX.alongthickness.cathode_active_material_potential; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_AMREX2 = app.filenames.AMREX.alongthickness.anode_active_material_potential; filefolder_AMREX2 = app.findfile(currentpath, f_AMREX2);
                    f_FENICS = app.filenames.FENICS.alongthickness.cathode_active_material_potential; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_FENICS2 = app.filenames.FENICS.alongthickness.anode_active_material_potential; filefolder_FENICS2 = app.findfile(currentpath, f_FENICS2);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.cathode_active_material_potential; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                    f_PYBAMM2 = app.filenames.PYBAMM.alongthickness.anode_active_material_potential; filefolder_PYBAMM2 = app.findfile(currentpath, f_PYBAMM2);
                elseif strcmp(domain,'Anode electrolyte')
                    f_AMREX = app.filenames.AMREX.alongthickness.anode_electrolyte_potential; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.electrolyte_potential; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.electrolyte_potential; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Separator')
                    f_AMREX = app.filenames.AMREX.alongthickness.separator_potential; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.electrolyte_potential; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.electrolyte_potential; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Cathode electrolyte')
                    f_AMREX = app.filenames.AMREX.alongthickness.cathode_electrolyte_potential; filefolder_AMREX = app.findfile(currentpath, f_AMREX);
                    f_FENICS = app.filenames.FENICS.alongthickness.electrolyte_potential; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.electrolyte_potential; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                elseif strcmp(domain,'Electrolyte')
                    f_AMREX = app.filenames.AMREX.alongthickness.cathode_electrolyte_potential; filefolder_AMREX3 = app.findfile(currentpath, f_AMREX);
                    f_AMREX2 = app.filenames.AMREX.alongthickness.separator_potential; filefolder_AMREX2 = app.findfile(currentpath, f_AMREX2);
                    f_AMREX3 = app.filenames.AMREX.alongthickness.anode_electrolyte_potential; filefolder_AMREX = app.findfile(currentpath, f_AMREX3);
                    f_FENICS = app.filenames.FENICS.alongthickness.electrolyte_potential; filefolder_FENICS = app.findfile(currentpath, f_FENICS);
                    f_PYBAMM = app.filenames.PYBAMM.alongthickness.electrolyte_potential; filefolder_PYBAMM = app.findfile(currentpath, f_PYBAMM);
                end

            end

            if ~isempty(filefolder_AMREX)
                if ~isempty(filefolder_AMREX2) && ~isempty(filefolder_AMREX3)
                    T = readtable([filefolder_AMREX app.delimiter f_AMREX],'NumHeaderLines',1);
                    T2 = readtable([filefolder_AMREX2 app.delimiter f_AMREX2],'NumHeaderLines',1);
                    T3 = readtable([filefolder_AMREX3 app.delimiter f_AMREX3],'NumHeaderLines',1);
                    Vals= [T.Variables; T2.Variables; T3.Variables];
                    idNaN = isnan(Vals(:,5));
                    Vals(idNaN,:)=[];
                    Vals=sortrows(Vals,1);
                    tmp=diff(Vals(:,3));
                    duplicate = find(tmp==0);
                    Vals(duplicate,:)=[];
                elseif ~isempty(filefolder_AMREX2) && isempty(filefolder_AMREX3)
                    T = readtable([filefolder_AMREX app.delimiter f_AMREX],'NumHeaderLines',1);
                    T2 = readtable([filefolder_AMREX2 app.delimiter f_AMREX2],'NumHeaderLines',1);
                    Vals= [T.Variables; T2.Variables;];
                    idNaN = isnan(Vals(:,5));
                    Vals(idNaN,:)=[];
                    Vals=sortrows(Vals,1);
                    tmp=diff(Vals(:,3));
                    duplicate = find(tmp==0);
                    Vals(duplicate,:)=[];
                else
                    T = readtable([filefolder_AMREX app.delimiter f_AMREX],'NumHeaderLines',1);
                    Vals= T.Variables;
                    idNaN = isnan(Vals(:,5));
                    Vals(idNaN,:)=[];
                    
                end
                reverse_xaxis = false;
                Vals(:,3) = Vals(:,3)*1e6;
            elseif ~isempty(filefolder_FENICS)
                T = readtable([filefolder_FENICS app.delimiter f_FENICS],'NumHeaderLines',1);
                Vals= T.Variables;
                tmp = app.findfile(currentpath, app.filenames.FENICS.Ce_at_rest );
                if strcmp(field,'Concentration')
                    if strcmp(domain,'Electrolyte') || strcmp(domain,'Anode electrolyte') || strcmp(domain,'Separator') || strcmp(domain,'Cathode electrolyte')
                        Ce_norm = importdata([tmp app.delimiter app.filenames.FENICS.Ce_at_rest ]);
                    elseif strcmp(domain,'Anode active material')
                        Ce_norm = importdata([tmp app.delimiter app.filenames.FENICS.Cs_max_anode ]);
                    elseif strcmp(domain,'Cathode active material')
                        Ce_norm = importdata([tmp app.delimiter app.filenames.FENICS.Cs_max_cathode ]);
                    end
                    Vals(:,4:7) = Vals(:,4:7)*Ce_norm;
                end
                reverse_xaxis = true;
                Vals(:,3) = Vals(:,3)*1e6;
            elseif ~isempty(filefolder_PYBAMM)
                T = readtable([filefolder_PYBAMM app.delimiter f_PYBAMM]);
                tmp= T.Variables;
                time = tmp(1,2:end); n_time = length(time);
                pos = tmp(2:end,1)*1e6; n_pos = length(pos);
                Vals = zeros(n_time*n_pos,7);
                r0=0;
                for ktime=1:1:n_time
                    Vals(r0+1:r0+n_pos,1)=ktime-1;
                    Vals(r0+1:r0+n_pos,2)=time(ktime);
                    Vals(r0+1:r0+n_pos,3)=pos;
                    Vals(r0+1:r0+n_pos,4)=tmp(2:end,ktime+1);
                    Vals(r0+1:r0+n_pos,5)=tmp(2:end,ktime+1);
                    Vals(r0+1:r0+n_pos,6)=tmp(2:end,ktime+1);
                    r0 = r0+n_pos;
                end
                reverse_xaxis = false;
            else
                app.AlongDirection_StatutTextArea.Value = ['WARNING: File not found for ' currentpath];
                Vals = [];
            end

            if reverse_xaxis
                x_min = 0;
                x_max = 109.89;
                x_max = 50.0;
                x_max = 50.0;
                a = (x_min-x_max)/(x_max-x_min);
                b = x_max-a*x_min;
                Vals(:,3) = a*Vals(:,3)+b;
                all_step = unique(Vals(:,1));
                n_step = length(all_step);
                for k_step = 1:1:n_step
                    ids = find(Vals(:,1)==all_step(k_step));
                    row_a = min(ids);
                    row_b = max(ids);
                    tmp = Vals(row_a:row_b,:);
                    tmp=sortrows(tmp,3);
                    Vals(row_a:row_b,:) = tmp;
                end   
            end

            
        end
        
        function DataField = Interpolate_Vals_along_direction(app,DataField,dt,force)
            n_input = length(DataField);
            all_t = [];
            for k=1:1:n_input

                DataField(k).t = unique(DataField(k).Vals(:,2));
                DataField(k).pos = unique(DataField(k).Vals(:,3));
                n_t = length(DataField(k).t);
                n_pos = length(DataField(k).pos);
                all_t = [all_t; DataField(k).t];

                DataField(k).min = zeros(n_pos,n_t);
                kp=0;
                for kt=1:1:n_t
                    DataField(k).min(:,kt)=DataField(k).Vals(kp+1:kp+n_pos,4);
                    kp=kp+n_pos;
                end
                DataField(k).mean = zeros(n_pos,n_t);
                kp=0;
                for kt=1:1:n_t
                    DataField(k).mean(:,kt)=DataField(k).Vals(kp+1:kp+n_pos,5);
                    kp=kp+n_pos;
                end
                DataField(k).max = zeros(n_pos,n_t);
                kp=0;
                for kt=1:1:n_t
                    DataField(k).max(:,kt)=DataField(k).Vals(kp+1:kp+n_pos,6);
                    kp=kp+n_pos;
                end
                DataField(k).std = zeros(n_pos,n_t);
                kp=0;
                for kt=1:1:n_t
                    DataField(k).std(:,kt)=DataField(k).Vals(kp+1:kp+n_pos,7);
                    kp=kp+n_pos;
                end   
            end

            if n_input>1 || force
                all_t = sort(unique(all_t));
                for k=1:1:n_input
                    t = DataField(k).t;
                    pos = DataField(k).pos;
                    [x,y]=ndgrid(pos,t);

                    z = DataField(k).mean;
                    % figure; surf(x,y,z);
                    F = griddedInterpolant(x,y,z,'linear','none');
                    t_interp = 0:dt:max(all_t);
                    pos_inter = pos;
                    DataField(k).pos_inter = pos_inter;
                    DataField(k).t_interp = t_interp;
                    [X,Y] = ndgrid(pos_inter,t_interp);
                    DataField(k).mean_inter = F(X,Y);
                    % figure; surf(X,Y,DataField(k).mean_inter);
                    
                    z = DataField(k).min;
                    F = griddedInterpolant(x,y,z,'linear','none');
                    DataField(k).min_inter = F(X,Y);

                    z = DataField(k).max;
                    F = griddedInterpolant(x,y,z,'linear','none');
                    DataField(k).max_inter = F(X,Y);

                    z = DataField(k).std;
                    F = griddedInterpolant(x,y,z,'linear','none');
                    DataField(k).std_inter = F(X,Y);   
                end   
            end

        end
        
        function results = func4(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ispc
                app.delimiter = '\';
            else
                app.delimiter = '/';
            end
            app.filenames.AMREX.alongthickness.anode_active_material_concentration = 'Anode_active_material_concentration.csv';
            app.filenames.AMREX.alongthickness.anode_active_material_potential = 'Anode_active_material_potential.csv';
            app.filenames.AMREX.alongthickness.anode_active_CBD_potential = 'Anode_CBD_potential.csv';
            app.filenames.AMREX.alongthickness.anode_electrolyte_concentration = 'Anode_electrolyte_concentration.csv';
            app.filenames.AMREX.alongthickness.anode_electrolyte_potential = 'Anode_electrolyte_potential.csv';
            app.filenames.AMREX.alongthickness.cathode_active_material_concentration = 'Cathode_active_material_concentration.csv';
            app.filenames.AMREX.alongthickness.cathode_active_material_potential = 'Cathode_active_material_potential.csv';
            app.filenames.AMREX.alongthickness.cathode_active_CBD_potential = 'Cathode_CBD_potential.csv';
            app.filenames.AMREX.alongthickness.cathode_electrolyte_concentration = 'Cathode_electrolyte_concentration.csv';
            app.filenames.AMREX.alongthickness.cathode_electrolyte_potential = 'Cathode_electrolyte_potential.csv';
            app.filenames.AMREX.alongthickness.separator_concentration = 'Separator_concentration.csv';
            app.filenames.AMREX.alongthickness.separator_potential = 'Separator_potential.csv';

            app.filenames.FENICS.alongthickness.anode_active_material_concentration = 'Concentration_RightAM_dir0.csv';
            app.filenames.FENICS.alongthickness.anode_active_material_potential = 'Potential_RightAM_dir0.csv';
            app.filenames.FENICS.alongthickness.cathode_active_material_concentration = 'Concentration_LeftAM_dir0.csv';
            app.filenames.FENICS.alongthickness.cathode_active_material_potential = 'Potential_LeftAM_dir0.csv';
            app.filenames.FENICS.alongthickness.electrolyte_concentration = 'Concentration_ELSEP_dir0.csv';
            app.filenames.FENICS.alongthickness.electrolyte_potential = 'Potential_ELSEP_dir0.csv';   

            app.filenames.FENICS.Ce_at_rest = 'Ce_atrest.txt';  
            app.filenames.FENICS.Cs_max_cathode = 'Cs_max_left.txt'; 
            app.filenames.FENICS.Cs_max_anode = 'Cs_max_right.txt'; 

            app.filenames.PYBAMM.alongthickness.anode_active_material_concentration = 'R-averaged negative particle concentration [mol.m-3].csv';
            app.filenames.PYBAMM.alongthickness.anode_active_material_potential = 'Negative electrode potential [V].csv';
            app.filenames.PYBAMM.alongthickness.cathode_active_material_concentration = 'R-averaged positive particle concentration [mol.m-3].csv';
            app.filenames.PYBAMM.alongthickness.cathode_active_material_potential = 'Positive electrode potential [V].csv';
            app.filenames.PYBAMM.alongthickness.electrolyte_concentration = 'Electrolyte concentration [mol.m-3].csv';
            app.filenames.PYBAMM.alongthickness.electrolyte_potential = 'Electrolyte potential [V].csv';                
            

        end

        % Button pushed function: SelectresultfolderButton
        function SelectresultfolderButtonPushed(app, event)
            if isempty(app.currentfolderpath)
                selpath = uigetdir('Select root folder that contains csv result files');
            else
                selpath = uigetdir(app.currentfolderpath,'Select root folder that contains csv result files');
            end
            if selpath==0 % User clicked cancel button or closed the dialog box
                app.currentfolderpath = [];
            else % User selected a folder
                app.number_simulation = app.number_simulation+1;
                app.simulations(app.number_simulation).folderpath=selpath;
                app.currentfolderpath = selpath;
                newStr = split(selpath,app.delimiter);

                % % Update tables

                %  Import
                new_line = cell(1,5);
                new_line(1,1) = newStr(end,1);
                tmp = app.Import_UITable.Data;
                if isempty(tmp)
                    app.Import_UITable.Data=new_line;
                else
                    app.Import_UITable.Data(app.number_simulation,:)=new_line;
                end

                % Along directions
                % Cell voltage
                new_line = cell(1,4);
                new_line(1,1) = newStr(end,1);
                new_line(1,2) = new_line(1,1);
                new_line(1,3) = {true};
                new_line(1,4) = {false};
                tmp = app.PlotAlongDirection_UITable.Data;
                if isempty(tmp)
                    app.PlotAlongDirection_UITable.Data=new_line;
                    app.PlotCellvoltage_UITable.Data=new_line;
                else
                    app.PlotAlongDirection_UITable.Data(app.number_simulation,:)=new_line;
                    app.PlotCellvoltage_UITable.Data(app.number_simulation,:)=new_line;
                end 
            end
        end

        % Button pushed function: AlongDirection_PlotButton
        function AlongDirection_PlotButtonPushed(app, event)
            % % Prepare data

            Field1 = app.Field1leftyaxisDropDown.Value;
            Field2 = app.Field2rightyaxisDropDown.Value;
            Domain1 = app.Domain1DropDown.Value;
            Domain2 = app.Domain2DropDown.Value;
            Domain3 = app.Domain3DropDown.Value;

            idplot = find(cell2mat(app.PlotAlongDirection_UITable.Data(:,3)));
            if ~isempty(idplot)
                
                % Import data
                app.AlongDirection_StatutTextArea.Value = 'Import data...'; pause(0.01);
                for k=1:1:length(idplot)
                    currentpath = app.simulations(idplot(k)).folderpath;
                    Data_field1_domain1(k).Vals = app.Select_data_alongdirection(currentpath,Field1,Domain1);
                    Data_field1_domain1(k).name = [char(app.PlotAlongDirection_UITable.Data(idplot(k),2)) ', ' Domain1];
                    if ~strcmp(Field2,'None')
                        Data_field2_domain1(k).Vals = app.Select_data_alongdirection(currentpath,Field2,Domain1);
                    else
                        Data_field2_domain1 = [];
                    end

                    if ~strcmp(Domain2,'None')
                        Data_field1_domain2(k).Vals = app.Select_data_alongdirection(currentpath,Field1,Domain2);
                        Data_field1_domain2(k).name = [char(app.PlotAlongDirection_UITable.Data(idplot(k),2)) ', ' Domain2];
                        if ~strcmp(Field2,'None')
                            Data_field2_domain2(k).Vals = app.Select_data_alongdirection(currentpath,Field2,Domain2);
                        else
                            Data_field2_domain2 = [];
                        end
                    else
                        Data_field1_domain2 = [];
                        Data_field2_domain2 = [];
                    end

                    if ~strcmp(Domain3,'None')
                        Data_field1_domain3(k).Vals = app.Select_data_alongdirection(currentpath,Field1,Domain3);
                        Data_field1_domain3(k).name = [char(app.PlotAlongDirection_UITable.Data(idplot(k),2)) ', ' Domain3];
                        if ~strcmp(Field2,'None')
                            Data_field2_domain3(k).Vals = app.Select_data_alongdirection(currentpath,Field2,Domain3);
                        else
                            Data_field2_domain3 = [];
                        end
                    else
                        Data_field1_domain3 = [];
                        Data_field2_domain3 = [];
                    end

                end

                % Process data
                force_interpolation = app.ForceinterpolationifimportonlyoneresultCheckBox_2.Value;
                %if length(idplot)>1 || app.ForceinterpolationifimportonlyoneresultCheckBox_2.Value
                    dt = app.AlongDirection_SteptimesEditField.Value;
                    app.AlongDirection_StatutTextArea.Value = 'Interpolate data...'; pause(0.01);
                    Data_field1_domain1 = app.Interpolate_Vals_along_direction(Data_field1_domain1,dt,true);
                    if ~isempty(Data_field2_domain1)
                        Data_field2_domain1 = app.Interpolate_Vals_along_direction(Data_field2_domain1,dt,true);
                    end

                    if ~strcmp(Domain2,'None')
                        Data_field1_domain2 = app.Interpolate_Vals_along_direction(Data_field1_domain2,dt,true);
                        if ~isempty(Data_field2_domain2)
                            Data_field2_domain2 = app.Interpolate_Vals_along_direction(Data_field2_domain2,dt,true);
                        end
                    end
                    if ~strcmp(Domain3,'None')
                        Data_field1_domain3 = app.Interpolate_Vals_along_direction(Data_field1_domain3,dt,true);
                        if ~isempty(Data_field2_domain3)
                            Data_field2_domain3 = app.Interpolate_Vals_along_direction(Data_field2_domain3,dt,true);
                        end
                    end
                %end

                % Call app
                app.AlongDirection_StatutTextArea.Value = 'Plotting...'; pause(0.01);
                Batterymodel_postprocessing_alongdirection(Data_field1_domain1,Data_field2_domain1,Data_field1_domain2,Data_field2_domain2,Data_field1_domain3,Data_field2_domain3,Field1,Field2,Domain1,Domain2,Domain3,force_interpolation);

                app.AlongDirection_StatutTextArea.Value = 'Done...'; pause(0.01);
            else
                app.AlongDirection_StatutTextArea.Value = 'No file selected for plotting';

            end

        end

        % Value changed function: Domain2DropDown, Domain3DropDown
        function Domain2DropDownValueChanged(app, event)
            if strcmp(app.Domain2DropDown.Value,'None')
                app.Domain3DropDown.Value = 'None';
            end
        end

        % Button pushed function: Cellvoltage_PlotButton
        function Cellvoltage_PlotButtonPushed(app, event)
            idplot = find(cell2mat(app.PlotCellvoltage_UITable.Data(:,3)));
            idref = find(cell2mat(app.PlotCellvoltage_UITable.Data(:,4)));
            if length(idref)<=1
                ids = unique([idplot; idref]);
                if ~isempty(idplot)

                    % Import and remove un-ncesseary data
                    app.Cellvoltage_StatutTextArea.Value = 'Import data and remove non-extremities values...'; pause(0.01);
                    for k=1:1:length(ids)
                        currentpath = app.simulations(ids(k)).folderpath;
                        AnodePotential(k).Vals = app.Select_data_alongdirection(currentpath,'Potential','Anode active material');
                        CathodePotential(k).Vals = app.Select_data_alongdirection(currentpath,'Potential','Cathode active material');
                        x_anode = unique(AnodePotential(k).Vals (:,3));
                        x_cathode = unique(CathodePotential(k).Vals (:,3));
                        minmax_x_anode = [min(x_anode) max(x_anode)];
                        minmax_x_cathode = [min(x_cathode) max(x_cathode)];
                        if minmax_x_cathode(2)-minmax_x_anode(1) > minmax_x_anode(2)-minmax_x_cathode(1)
                            terminal_x_anode = minmax_x_anode(1);
                            terminal_x_cathode = minmax_x_cathode(2);
                        else
                            terminal_x_anode = minmax_x_anode(2);
                            terminal_x_cathode = minmax_x_cathode(1);
                        end
                        id_anode = find(AnodePotential(k).Vals(:,3) ~= terminal_x_anode);
                        id_cathode = find(CathodePotential(k).Vals(:,3) ~= terminal_x_cathode);
                        AnodePotential(k).Vals(id_anode,:) = [];
                        CathodePotential(k).Vals(id_cathode,:) = [];
                    end

                    % Process data
                    if length(ids)>1 || app.ForceinterpolationifimportonlyoneresultCheckBox.Value
                        dt = app.Cellvoltage_SteptimesEditField.Value;
                        app.Cellvoltage_StatutTextArea.Value = 'Interpolate data...'; pause(0.01);

                        all_t = [];
                        for k=1:1:length(ids)
                            all_t = [all_t; AnodePotential(k).Vals(:,2)];
                        end
                        all_t = sort(unique(all_t));
                        t_interp = 0:dt:max(all_t);
                        for k=1:1:length(ids)
                            tmp = zeros(length(t_interp),7);
                            tmp(:,2) = t_interp;
                            t = AnodePotential(k).Vals(:,2);
                            tmp(:,5) = interp1(t,AnodePotential(k).Vals(:,5),t_interp,'linear');
                            AnodePotential(k).Vals = tmp;
                            k

                            tmp(:,5) = interp1(t,CathodePotential(k).Vals(:,5),t_interp,'linear');
                            CathodePotential(k).Vals = tmp;
                        end
                    end

                    if ~isempty(idref)
                        yref = CathodePotential(idref).Vals(:,5) - AnodePotential(idref).Vals(:,5);
                    end

                    % Plotting
                    app.Cellvoltage_StatutTextArea.Value = 'Plotting...'; pause(0.01);

                    Fig = figure; % Create figure
                    Fig.Name= 'Cell voltage'; % Figure name
                    Fig.Color='white'; % Background colour
                    ax_ = axes('Parent',Fig);
                    h_title=title ('Cell voltage');
                    hold(ax_,'on');
                        
                    for k=1:1:length(idplot)
                        x = CathodePotential(k).Vals(:,2);
                        y = CathodePotential(k).Vals(:,5) - AnodePotential(k).Vals(:,5);
                        if isempty(idref)
                            % % Remove analytical initialization
                            % id0 = x ==0;
                            % if ~isempty(id0)
                            %   x(id0)=[];
                            %   y(id0)=[];
                            % end
                            % % Translate to 0s
                            % x = x -min(x);
                            plot(x,y,'LineWidth',2,'DisplayName',char(app.PlotCellvoltage_UITable.Data(idplot(k),2)));
                        else
                            y = y - yref;
                            if k~=idref
                                plot(x,y,'LineWidth',2,'DisplayName',char(app.PlotCellvoltage_UITable.Data(idplot(k),2)));
                            else
                                plot(x,y,'LineWidth',2,'DisplayName',['(Ref) ' char(app.PlotCellvoltage_UITable.Data(idplot(k),2))]);
                            end
                        end
                    end
                    % Axis label
                    xlabel('Times (s)');
                    ylabel('Cell voltage (V)');
                    % Legend
                    h_legend = legend(ax_,'Location','best');
                    % - Grid
                    grid(ax_,'on'); % Display grid
                    set(ax_,'XMinorGrid','off','YMinorGrid','off');
                    set(ax_,'FontName','Times new roman','FontSize',14); % Fontname and fontsize
                    h_title.FontSize = 16; % Set title fontsize
                    h_legend.FontSize = 12; % Set title fontsize
                    hold(ax_,'off'); % Relase figure

                    app.Cellvoltage_StatutTextArea.Value = 'Done'; pause(0.01);
                else
                    app.Cellvoltage_StatutTextArea.Value = 'No file selected for plotting';

                end
            end

        end

        % Cell edit callback: PlotCellvoltage_UITable
        function PlotCellvoltage_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==4
                if newData
                    tmp = app.PlotCellvoltage_UITable.Data(:,4);
                    new = cell(length(tmp),1);
                    for k=1:1:length(tmp)
                        new(k,1) = {false};
                    end
                    new(indices(1),1) = {true};
                    app.PlotCellvoltage_UITable.Data(:,4) = new;
                    %app.PlotCellvoltage_UITable.Data(indices(1),3) = {false};
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.SelectresultfolderButton = uibutton(app.ImportTab, 'push');
            app.SelectresultfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectresultfolderButtonPushed, true);
            app.SelectresultfolderButton.Text = 'Select result folder';
            app.ImportLabel = uilabel(app.ImportTab);
            app.ImportLabel.Text = 'Import';
            app.Import_UITable = uitable(app.ImportTab);
            app.Import_UITable.ColumnName = {'Name'; 'Time'; 'C-rate (pratical/theroritical)'; 'SOC anode (start/end)'; 'SOC cathode (start/end)'};
            app.Import_UITable.RowName = {};
            app.HardcodedcnorminalongdirectionmlappLabel = uilabel(app.ImportTab);
            app.HardcodedcnorminalongdirectionmlappLabel.Text = {'Hard coded: '; 'x_max during import (for reverse axis)'; 'c norm in alongdirection.mlapp'};
            app.AlongthicknessTab = uitab(app.TabGroup);
            app.AlongthicknessTab.Title = 'Along thickness';
            app.PlotsolutionalongthicknessLabel = uilabel(app.AlongthicknessTab);
            app.PlotsolutionalongthicknessLabel.Text = 'Plot solution along thickness';
            app.PlotAlongDirection_UITable = uitable(app.AlongthicknessTab);
            app.PlotAlongDirection_UITable.ColumnName = {'Name'; 'Legend'; 'Plot'};
            app.PlotAlongDirection_UITable.RowName = {};
            app.PlotAlongDirection_UITable.ColumnEditable = [false true true];
            app.Field1leftyaxisDropDownLabel = uilabel(app.AlongthicknessTab);
            app.Field1leftyaxisDropDownLabel.Text = 'Field 1 (left y-axis)';
            app.Field1leftyaxisDropDown = uidropdown(app.AlongthicknessTab);
            app.Field1leftyaxisDropDown.Items = {'Concentration', 'Potential'};
            app.Field1leftyaxisDropDown.Value = 'Concentration';
            app.Field2rightyaxisDropDownLabel = uilabel(app.AlongthicknessTab);
            app.Field2rightyaxisDropDownLabel.Text = 'Field 2 (right y-axis)';
            app.Field2rightyaxisDropDown = uidropdown(app.AlongthicknessTab);
            app.Field2rightyaxisDropDown.Items = {'None', 'Concentration', 'Potential'};
            app.Field2rightyaxisDropDown.Value = 'None';
            app.Domain1DropDownLabel = uilabel(app.AlongthicknessTab);
            app.Domain1DropDownLabel.Text = 'Domain 1';
            app.Domain1DropDown = uidropdown(app.AlongthicknessTab);
            app.Domain1DropDown.Items = {'Anode active material', 'Cathode active material', 'Anode electrolyte', 'Separator', 'Cathode electrolyte', 'Electrolyte'};
            app.Domain1DropDown.Value = 'Anode electrolyte';
            app.AlongDirection_PlotButton = uibutton(app.AlongthicknessTab, 'push');
            app.AlongDirection_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @AlongDirection_PlotButtonPushed, true);
            app.AlongDirection_PlotButton.Text = 'Plot';
            app.StatutTextAreaLabel = uilabel(app.AlongthicknessTab);
            app.StatutTextAreaLabel.Text = 'Statut';
            app.AlongDirection_StatutTextArea = uitextarea(app.AlongthicknessTab);
            app.SteptimesEditFieldLabel = uilabel(app.AlongthicknessTab);
            app.SteptimesEditFieldLabel.Text = 'Step time (s)';
            app.AlongDirection_SteptimesEditField = uieditfield(app.AlongthicknessTab, 'numeric');
            app.AlongDirection_SteptimesEditField.Limits = [0 Inf];
            app.AlongDirection_SteptimesEditField.Value = 1;
            app.Domain2DropDownLabel = uilabel(app.AlongthicknessTab);
            app.Domain2DropDownLabel.Text = 'Domain 2';
            app.Domain2DropDown = uidropdown(app.AlongthicknessTab);
            app.Domain2DropDown.Items = {'Anode active material', 'Cathode active material', 'Anode electrolyte', 'Separator', 'Cathode electrolyte', 'Electrolyte', 'None'};
            app.Domain2DropDown.ValueChangedFcn = createCallbackFcn(app, @Domain2DropDownValueChanged, true);
            app.Domain2DropDown.Value = 'None';
            app.Domain3DropDownLabel = uilabel(app.AlongthicknessTab);
            app.Domain3DropDownLabel.Text = 'Domain 3';
            app.Domain3DropDown = uidropdown(app.AlongthicknessTab);
            app.Domain3DropDown.Items = {'Anode active material', 'Cathode active material', 'Anode electrolyte', 'Separator', 'Cathode electrolyte', 'Electrolyte', 'None'};
            app.Domain3DropDown.ValueChangedFcn = createCallbackFcn(app, @Domain2DropDownValueChanged, true);
            app.Domain3DropDown.Value = 'None';
            app.ForceinterpolationifimportonlyoneresultCheckBox_2 = uicheckbox(app.AlongthicknessTab);
            app.ForceinterpolationifimportonlyoneresultCheckBox_2.Text = 'Force interpolation if import only one result';
            app.CellvoltageTab = uitab(app.TabGroup);
            app.CellvoltageTab.Title = 'Cell voltage';
            app.PlotCellvoltage_UITable = uitable(app.CellvoltageTab);
            app.PlotCellvoltage_UITable.ColumnName = {'Name'; 'Legend'; 'Plot'; 'Reference'};
            app.PlotCellvoltage_UITable.RowName = {};
            app.PlotCellvoltage_UITable.ColumnEditable = [false true true true];
            app.PlotCellvoltage_UITable.CellEditCallback = createCallbackFcn(app, @PlotCellvoltage_UITableCellEdit, true);
            app.PlotcellvoltageLabel = uilabel(app.CellvoltageTab);
            app.PlotcellvoltageLabel.Text = 'Plot cell voltage';
            app.Cellvoltage_PlotButton = uibutton(app.CellvoltageTab, 'push');
            app.Cellvoltage_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Cellvoltage_PlotButtonPushed, true);
            app.Cellvoltage_PlotButton.Text = 'Plot';
            app.StatutTextAreaLabel_2 = uilabel(app.CellvoltageTab);
            app.StatutTextAreaLabel_2.Text = 'Statut';
            app.Cellvoltage_StatutTextArea = uitextarea(app.CellvoltageTab);
            app.SteptimesEditFieldLabel_2 = uilabel(app.CellvoltageTab);
            app.SteptimesEditFieldLabel_2.Text = 'Step time (s)';
            app.Cellvoltage_SteptimesEditField = uieditfield(app.CellvoltageTab, 'numeric');
            app.Cellvoltage_SteptimesEditField.Limits = [0 Inf];
            app.Cellvoltage_SteptimesEditField.Value = 1;
            app.ForceinterpolationifimportonlyoneresultCheckBox = uicheckbox(app.CellvoltageTab);
            app.ForceinterpolationifimportonlyoneresultCheckBox.Text = 'Force interpolation if import only one result';
            app.AveragedDOFTab = uitab(app.TabGroup);
            app.AveragedDOFTab.Title = 'Averaged DOF';
            app.MasserrorTab = uitab(app.TabGroup);
            app.MasserrorTab.Title = 'Mass error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Batterymodel_postprocessing_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end